classdef PIETOOLS_2021a < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIETOOLS2021aUIFigure       matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        AddnewBCButton              matlab.ui.control.Button
        AddtoPanel                  matlab.ui.container.Panel
        DynamicsButton              matlab.ui.control.Button
        LHSDropDown                 matlab.ui.control.DropDown
        BCNumberButton              matlab.ui.control.Button
        BCNumber                    matlab.ui.control.DropDown
        ORLabel                     matlab.ui.control.Label
        ORLabel_2                   matlab.ui.control.Label
        OutputButton                matlab.ui.control.Button
        LHSDropDown_output          matlab.ui.control.DropDown
        AddTermPanel                matlab.ui.container.Panel
        Coefficient                 matlab.ui.control.EditField
        ProductList                 matlab.ui.control.DropDown
        TermType                    matlab.ui.control.DropDown
        IntVariable                 matlab.ui.control.DropDown
        aEditFieldLabel             matlab.ui.control.Label
        lowerLimit                  matlab.ui.control.EditField
        bEditFieldLabel             matlab.ui.control.Label
        UpperLimit                  matlab.ui.control.EditField
        ConverttoPIEButton          matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
        DynamicsEqns                matlab.ui.control.UIAxes
        BCEqns                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        ODElist                     matlab.ui.control.Table
        VariableNameEditFieldLabel  matlab.ui.control.Label
        VariableNameEditField       matlab.ui.control.EditField
        PDElist                     matlab.ui.control.Table
        Inputlist                   matlab.ui.control.Table
        AddVar                      matlab.ui.control.Button
        VarType                     matlab.ui.control.DropDown
        Disturblist                 matlab.ui.control.Table
        RegOutlist                  matlab.ui.control.Table
        ObsOutlist                  matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeGUI(app)
            set(app.LHSDropDown,'Items',{});
            set(app.ProductList,'Items',{});
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PIETOOLS2021aUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {519, 519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {249, '1x', 252};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function
        function addstatePDE(app, event)
            % need to check if the variable already exists and then add
            
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));

            
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,state_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),state_name)) && ...
                    ~any(strcmp(Inputvarlist,state_name))
                tabledata = get(app.PDElist, 'Data');
                tabledata = [tabledata; [state_name 0]];
                set(app.PDElist, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = ['$', state_name_t,' = 0$'];
            set(app.TextArea,'Value',textDyn);
            
            textDyn=erase(textDyn,'*');
            cla(app.DynamicsEqns);
            text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(s)'];
            RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(\theta)'];
            set(app.ProductList,'Items', RHS_terms_list);
            end
        end

        % Callback function
        function addstateODE(app, event)
            % need to check if the variable already exists and then add
            state_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,state_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),state_name)) && ...
                    ~any(strcmp(Inputvarlist,state_name))
                tabledata = get(app.ODElist, 'Data');
                tabledata = [tabledata; state_name];
                set(app.ODElist, 'Data', tabledata);
            
            % add state to dynamics
            state_name_t = convertStringsToChars(strcat(state_name,"_t"));
            dynamics_list = get(app.LHSDropDown,'Items');
            
            dynamics_list{end+1} = state_name_t;
            set(app.LHSDropDown,'Items', dynamics_list);
            
            textDyn = get(app.TextArea,'Value');
            textDyn{end+1} = ['$',state_name_t,' = 0$'];
            set(app.TextArea,'Value',textDyn);
            
            textDyn=erase(textDyn,'*');
            cla(app.DynamicsEqns);
            text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.ProductList,'Items', RHS_terms_list);
            end
            
        end

        % Callback function
        function addInput(app, event)
            % need to check if the variable already exists and then add
            input_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,input_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),input_name)) && ...
                    ~any(strcmp(Inputvarlist,input_name))
            tabledata = get(app.Inputlist, 'Data');
            tabledata = [tabledata; input_name];
            set(app.Inputlist, 'Data', tabledata);
            
            RHS_terms_list = get(app.ProductList, 'Items');
            RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
            set(app.ProductList,'Items', RHS_terms_list);
            end
        end

        % Button pushed function: DynamicsButton
        function addTerms2Dynamics(app, event)
            a = get(app.lowerLimit, 'Value');
            b = get(app.UpperLimit, 'Value');
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            
            stateterm = get(app.ProductList,'Value');
            LHS = get(app.LHSDropDown,'Value');
            dynamicstype = LHS(1:end-2);
            
            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            if any(strcmp(ODEvarlist,dynamicstype)) 
                dynamicstype='ODE';
            elseif any(strcmp(PDEvarlist(:,1),dynamicstype))
                dynamicstype='PDE';
            end
            intvar = get(app.IntVariable,'Value');
            
            logicalval = isvalidPolynomial(coeff);
            
            coeff = replace(coeff,'theta','\theta');
            validTerm = isvalidTerm(coeff,termType,dynamicstype,stateterm,intvar);
            if logicalval==0 && validTerm==0
                textDyn = get(app.TextArea,'Value');
                idx = find(contains(textDyn,LHS));
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-1:end),'0$')
                    existingTxt=existingTxt(1:end-2);
                elseif strcmp(existingTxt(end),'$')
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm,'$'));
                    textDyn{idx} = replaceTxt;
                else
                    replaceTxt = strcat(existingTxt,['+\int_',get(app.lowerLimit, 'Value'),...
                        '^',get(app.UpperLimit, 'Value'),'(',coeff,')*',stateterm,...
                        get(app.IntVariable,'Value'),'$']);
                    textDyn{idx} = replaceTxt;
                end
                
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.DynamicsEqns);
                text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Cell edit callback: PDElist
        function changeDerivative(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            tabledata = get(app.PDElist,'Data');
            changedState = convertStringsToChars(tabledata(indices(1),1));
            list = get(app.ProductList,'Items');
            
            if str2double(event.NewData)>str2double(event.PreviousData)
                for i=str2double(event.PreviousData)+1:str2double(event.NewData)
                    list{end+1} = [changedState,'_{',repmat('s',1,i),'}(s)'];
                    list{end+1} = [changedState,'_{',repmat('s',1,i),'}(\theta)'];
                    j = i-1;
                    list{end+1} = [changedState,'_{',repmat('s',1,j),'}','(0)'];
                    list{end+1} = [changedState,'_{',repmat('s',1,j),'}','(1)'];
                end
            elseif str2double(event.NewData)<str2double(event.PreviousData)
                for i=str2double(event.NewData)+1:str2double(event.PreviousData)
                    list(strcmp(list,[changedState,'_{',repmat('s',1,i),'}(s)'])) = [];
                    list(strcmp(list,[changedState,'_{',repmat('s',1,i),'}(\theta)'])) = [];
                    j = i-1;
                    list(strcmp(list,[changedState,'_{',repmat('s',1,j),'}','(0)'])) = [];
                    list(strcmp(list,[changedState,'_{',repmat('s',1,j),'}','(1)'])) = [];
                end
            end
            set(app.ProductList,'Items',list);
        end

        % Button pushed function: BCNumberButton
        function addTerm2BC(app, event)
            a = get(app.lowerLimit, 'Value');
            b = get(app.UpperLimit, 'Value');
            stateterm = get(app.ProductList,'Value');
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            intvar= get(app.IntVariable,'Value');
            
            
            logicalval = isvalidPolynomial(coeff);
            
            
            coeff = replace(coeff,'theta','\theta');
            validTerm = isvalidTerm(coeff,termType,'BC',stateterm,intvar);
            
            if logicalval==0 && validTerm==0
                
                textDyn = get(app.TextArea_2,'Value');
                idx = str2double(get(app.BCNumber,'Value'));
                existingTxt = textDyn{idx+1};
                
                if strcmp(existingTxt(end-1:end),'0$')
                    existingTxt=existingTxt(1:end-2);
                elseif strcmp(existingTxt(end),'$')
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm,'$'));
                    textDyn{idx+1} = replaceTxt;
                else
                    replaceTxt = strcat(existingTxt,['+\int_',get(app.lowerLimit, 'Value'),...
                        '^',get(app.UpperLimit, 'Value'),'(',coeff,')*',stateterm,...
                        get(app.IntVariable,'Value'),'$']);
                    textDyn{idx+1} = replaceTxt;
                end
                
                set(app.TextArea_2,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.BCEqns);
                text(app.BCEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
           else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: AddnewBCButton
        function addBC(app, event)
            textBC = get(app.TextArea_2,'Value');
            textBC{end+1} = ['$0 = 0$'];
            BClist = get(app.BCNumber,'Items');
            BClist{end+1} = num2str(length(BClist)+1);
            
            set(app.BCNumber,'Items',BClist);
            set(app.TextArea_2,'Value',textBC);
            textBC=erase(textBC,'*');
            cla(app.BCEqns);
            text(app.BCEqns,0,1,textBC,'VerticalAlignment','top','interpreter','latex');
        end

        % Button pushed function: AddVar
        function addnewVar(app, event)
            var_name = convertCharsToStrings(get(app.VariableNameEditField, 'Value'));

            ODEvarlist = get(app.ODElist,'Data');
            PDEvarlist = get(app.PDElist,'Data');
            Disturbvarlist = get(app.Disturblist,'Data');
            Inputvarlist = get(app.Inputlist,'Data');
            RegOutvarlist = get(app.RegOutlist,'Data');
            ObsOutvarlist = get(app.ObsOutlist,'Data');
            
            if isempty(PDEvarlist)
                PDEvarlist = zeros([0,2]);
            end
            
            if ~any(strcmp(ODEvarlist,var_name)) && ...
                    ~any(strcmp(PDEvarlist(:,1),var_name)) && ...
                    ~any(strcmp(Disturbvarlist,var_name)) && ...
                    ~any(strcmp(Inputvarlist,var_name)) && ...
                    ~any(strcmp(RegOutvarlist,var_name)) && ...
                    ~any(strcmp(ObsOutvarlist,var_name)) 
                if strcmp(get(app.VarType, 'Value'),'PDE State')
                    tabledata = [PDEvarlist; [var_name 0]];
                    set(app.PDElist, 'Data', tabledata);
                    state_name_t = convertStringsToChars(strcat(var_name,"_t"));
                    dynamics_list = get(app.LHSDropDown,'Items');
                    dynamics_list{end+1} = state_name_t;
                    set(app.LHSDropDown,'Items', dynamics_list);
                    
                    textDyn = get(app.TextArea,'Value');
                    textDyn{end+1} = ['$', state_name_t,' = 0$'];
                    set(app.TextArea,'Value',textDyn);
                    
                    textDyn=erase(textDyn,'*');
                    cla(app.DynamicsEqns);
                    text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                    
                    RHS_terms_list = get(app.ProductList, 'Items');
                    RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(s)'];
                    RHS_terms_list{end+1} = [get(app.VariableNameEditField, 'Value'),'(\theta)'];
                    set(app.ProductList,'Items', RHS_terms_list);
                elseif strcmp(get(app.VarType, 'Value'),'ODE State')
                    tabledata = [ODEvarlist; var_name];
                    set(app.ODElist, 'Data', tabledata);
                    state_name_t = convertStringsToChars(strcat(var_name,"_t"));
                    dynamics_list = get(app.LHSDropDown,'Items');
                    
                    dynamics_list{end+1} = state_name_t;
                    set(app.LHSDropDown,'Items', dynamics_list);
                    
                    textDyn = get(app.TextArea,'Value');
                    textDyn{end+1} = ['$',state_name_t,' = 0$'];
                    set(app.TextArea,'Value',textDyn);
                    
                    textDyn=erase(textDyn,'*');
                    cla(app.DynamicsEqns);
                    text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                    
                    RHS_terms_list = get(app.ProductList, 'Items');
                    RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
                    set(app.ProductList,'Items', RHS_terms_list);
                elseif strcmp(get(app.VarType, 'Value'),'Input')
                    tabledata = [Inputvarlist; var_name];
                    set(app.Inputlist, 'Data', tabledata);
                    RHS_terms_list = get(app.ProductList, 'Items');
                    RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
                    set(app.ProductList,'Items', RHS_terms_list);
                elseif strcmp(get(app.VarType, 'Value'),'Disturbance')
                    tabledata = [Disturbvarlist; var_name];
                    set(app.Disturblist, 'Data', tabledata);
                    RHS_terms_list = get(app.ProductList, 'Items');
                    RHS_terms_list{end+1} = get(app.VariableNameEditField, 'Value');
                    set(app.ProductList,'Items', RHS_terms_list);
                elseif strcmp(get(app.VarType, 'Value'),'Regulated Output')
                    tabledata = [RegOutvarlist; var_name];
                    set(app.RegOutlist, 'Data', tabledata);
                    var_name = convertStringsToChars(var_name);
                    output_list = get(app.LHSDropDown_output,'Items');
                    output_list{end+1} = var_name;
                    set(app.LHSDropDown_output,'Items', output_list);
                    
                    textDyn = get(app.TextArea,'Value');
                    textDyn{end+1} = ['$', var_name,' = 0$'];
                    set(app.TextArea,'Value',textDyn);
                    
                    textDyn=erase(textDyn,'*');
                    cla(app.DynamicsEqns);
                    text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                else 
                    tabledata = [ObsOutvarlist; var_name];
                    set(app.ObsOutlist, 'Data', tabledata);
                    var_name=convertStringsToChars(var_name);
                    output_list = get(app.LHSDropDown_output,'Items');
                    output_list{end+1} = var_name;
                    set(app.LHSDropDown_output,'Items', output_list);
                    
                    textDyn = get(app.TextArea,'Value');
                    textDyn{end+1} = ['$', var_name,' = 0$'];
                    set(app.TextArea,'Value',textDyn);
                    
                    textDyn=erase(textDyn,'*');
                    cla(app.DynamicsEqns);
                    text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
                end
            end
        end

        % Button pushed function: OutputButton
        function addTerm2Output(app, event)
            a = get(app.lowerLimit, 'Value');
            b = get(app.UpperLimit, 'Value');
            termType = get(app.TermType,'Value');
            coeff = get(app.Coefficient,'Value');
            
            stateterm = get(app.ProductList,'Value');
            LHS = get(app.LHSDropDown_output,'Value');
            
            RegOutvarlist = get(app.ObsOutlist,'Data');
            ObsOutvarlist = get(app.RegOutlist,'Data');
            
            dynamicstype = 'ODE';
            
            intvar = get(app.IntVariable,'Value');
            
            logicalval = isvalidPolynomial(coeff);
            
            coeff = replace(coeff,'theta','\theta');
            validTerm = isvalidTerm(coeff,termType,dynamicstype,stateterm,intvar);
            if logicalval==0 && validTerm==0
                textDyn = get(app.TextArea,'Value');
                idx = find(contains(textDyn,LHS));
                existingTxt = textDyn{idx};
                
                if strcmp(existingTxt(end-1:end),'0$')
                    existingTxt=existingTxt(1:end-2);
                elseif strcmp(existingTxt(end),'$')
                    existingTxt=existingTxt(1:end-1);
                end
                
                if strcmp(termType,'Multiplier')
                    replaceTxt = strcat(existingTxt,strcat(['+(',coeff,')*'],stateterm,'$'));
                    textDyn{idx} = replaceTxt;
                else
                    replaceTxt = strcat(existingTxt,['+\int_',get(app.lowerLimit, 'Value'),...
                        '^',get(app.UpperLimit, 'Value'),'(',coeff,')*',stateterm,...
                        get(app.IntVariable,'Value'),'$']);
                    textDyn{idx} = replaceTxt;
                end
                
                set(app.TextArea,'Value',textDyn);
                textDyn=erase(textDyn,'*');
                cla(app.DynamicsEqns);
                text(app.DynamicsEqns,0,1,textDyn,'VerticalAlignment','top','interpreter','latex');
            elseif validTerm~=0
                tmp = errordlg("This term cannot be added to the selected equation");
            else
                if logicalval==2
                    tmp = errordlg("Input term has unidentified polynomial variable");
                elseif logicalval==3
                    tmp = errordlg("Input term has polynomial variable with non-integer exponent");
                else
                    tmp = errordlg("Input is not a valid polynomial");
                end
            end
        end

        % Button pushed function: ConverttoPIEButton
        function convert2PIE(app, event)
            PDEvars = get(app.PDElist,'Data');
            ODEvars = get(app.ODElist,'Data');
            disvars = get(app.Disturblist,'Data');
            inpvars = get(app.Inputlist,'Data');
            regvars = get(app.RegOutlist,'Data');
            obsvars = get(app.ObsOutlist,'Data');
            pdetext= executive_pde_text_cleaner(get(app.TextArea,'Value'),PDEvars,...
                ODEvars, disvars,inpvars,regvars,obsvars);
            bctext = executive_pde_text_cleaner(get(app.TextArea_2,'Value'),PDEvars,...
                ODEvars, disvars,inpvars,regvars,obsvars);
            pdetext = pdetext(2:end);
            bctext = bctext(2:end);
            PDE_obj = executive_pde_parser(pdetext,bctext, size(ODEvars,1),...
                PDEvars(:,2),size(disvars,1),size(inpvars,2),...
                size(regvars,1),size(obsvars,1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIETOOLS2021aUIFigure = uifigure('Visible', 'off');
            app.PIETOOLS2021aUIFigure.Name = 'PIETOOLS 2021a';
            app.PIETOOLS2021aUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PIETOOLS2021aUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddnewBCButton = uibutton(app.LeftPanel, 'push');
            app.AddnewBCButton.ButtonPushedFcn = createCallbackFcn(app, @addBC, true);
            app.AddnewBCButton.Tag = 'AddDynamicsTerms';
            app.AddnewBCButton.Text = 'Add new BC';
            app.AddtoPanel = uipanel(app.LeftPanel);
            app.AddtoPanel.Title = 'Add to:';
            app.DynamicsButton = uibutton(app.AddtoPanel, 'push');
            app.DynamicsButton.ButtonPushedFcn = createCallbackFcn(app, @addTerms2Dynamics, true);
            app.DynamicsButton.Tag = 'AddDynamicsTerms';
            app.DynamicsButton.Text = 'Dynamics';
            app.LHSDropDown = uidropdown(app.AddtoPanel);
            app.LHSDropDown.Items = {};
            app.LHSDropDown.Value = {};
            app.BCNumberButton = uibutton(app.AddtoPanel, 'push');
            app.BCNumberButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2BC, true);
            app.BCNumberButton.Tag = 'AddDynamicsTerms';
            app.BCNumberButton.Text = 'BC Number';
            app.BCNumber = uidropdown(app.AddtoPanel);
            app.BCNumber.Items = {};
            app.BCNumber.Value = {};
            app.ORLabel = uilabel(app.AddtoPanel);
            app.ORLabel.Text = 'OR';
            app.ORLabel_2 = uilabel(app.AddtoPanel);
            app.ORLabel_2.Text = 'OR';
            app.OutputButton = uibutton(app.AddtoPanel, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @addTerm2Output, true);
            app.OutputButton.Tag = 'AddDynamicsTerms';
            app.OutputButton.Text = 'Output';
            app.LHSDropDown_output = uidropdown(app.AddtoPanel);
            app.LHSDropDown_output.Items = {};
            app.LHSDropDown_output.Value = {};
            app.AddTermPanel = uipanel(app.LeftPanel);
            app.AddTermPanel.Title = 'Add Term';
            app.Coefficient = uieditfield(app.AddTermPanel, 'text');
            app.Coefficient.Tag = 'Coefficient';
            app.ProductList = uidropdown(app.AddTermPanel);
            app.ProductList.Tag = 'Int_Mul_state';
            app.TermType = uidropdown(app.AddTermPanel);
            app.TermType.Items = {'Multiplier', 'Integral', ''};
            app.TermType.Value = 'Multiplier';
            app.IntVariable = uidropdown(app.AddTermPanel);
            app.IntVariable.Items = {'ds', 'd\theta'};
            app.IntVariable.Tag = 'intVariable';
            app.IntVariable.Value = 'ds';
            app.aEditFieldLabel = uilabel(app.AddTermPanel);
            app.aEditFieldLabel.Text = 'a';
            app.lowerLimit = uieditfield(app.AddTermPanel, 'text');
            app.bEditFieldLabel = uilabel(app.AddTermPanel);
            app.bEditFieldLabel.Text = 'b';
            app.UpperLimit = uieditfield(app.AddTermPanel, 'text');
            app.ConverttoPIEButton = uibutton(app.LeftPanel, 'push');
            app.ConverttoPIEButton.ButtonPushedFcn = createCallbackFcn(app, @convert2PIE, true);
            app.ConverttoPIEButton.Text = 'Convert to PIE';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.CenterPanel);
            app.TextArea.Tag = 'Dynamics';
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.CenterPanel);
            app.TextArea_2.Tag = 'BCs';
            app.TextArea_2.Visible = 'off';
            app.DynamicsEqns = uiaxes(app.CenterPanel);
            title(app.DynamicsEqns, '')
            xlabel(app.DynamicsEqns, '')
            ylabel(app.DynamicsEqns, '')
            app.DynamicsEqns.XAxisLocation = 'origin';
            app.DynamicsEqns.XTick = [];
            app.DynamicsEqns.YAxisLocation = 'origin';
            app.DynamicsEqns.YTick = [];
            app.BCEqns = uiaxes(app.CenterPanel);
            title(app.BCEqns, '')
            xlabel(app.BCEqns, '')
            ylabel(app.BCEqns, '')
            app.BCEqns.XTick = [];
            app.BCEqns.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.ODElist = uitable(app.RightPanel);
            app.ODElist.ColumnName = {'ODE States'};
            app.ODElist.RowName = {};
            app.ODElist.Tag = 'ODElist';
            app.VariableNameEditFieldLabel = uilabel(app.RightPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.RightPanel, 'text');
            app.PDElist = uitable(app.RightPanel);
            app.PDElist.ColumnName = {'PDE States'; 'Highest Derivative'};
            app.PDElist.RowName = {};
            app.PDElist.ColumnEditable = [false true];
            app.PDElist.CellEditCallback = createCallbackFcn(app, @changeDerivative, true);
            app.PDElist.Tag = 'PDElist';
            app.Inputlist = uitable(app.RightPanel);
            app.Inputlist.ColumnName = {'Inputs'};
            app.Inputlist.RowName = {};
            app.Inputlist.Tag = 'Inputlist';
            app.AddVar = uibutton(app.RightPanel, 'push');
            app.AddVar.ButtonPushedFcn = createCallbackFcn(app, @addnewVar, true);
            app.AddVar.Text = 'Add';
            app.VarType = uidropdown(app.RightPanel);
            app.VarType.Items = {'PDE State', 'ODE State', 'Disturbance', 'Input', 'Regulated Output', 'Observed Output'};
            app.VarType.Value = 'PDE State';
            app.Disturblist = uitable(app.RightPanel);
            app.Disturblist.ColumnName = {'Disturbances'};
            app.Disturblist.RowName = {};
            app.Disturblist.Tag = 'Inputlist';
            app.RegOutlist = uitable(app.RightPanel);
            app.RegOutlist.ColumnName = {'Regulated Outputs'};
            app.RegOutlist.RowName = {};
            app.RegOutlist.Tag = 'Inputlist';
            app.ObsOutlist = uitable(app.RightPanel);
            app.ObsOutlist.ColumnName = {'Observed Outputs'};
            app.ObsOutlist.RowName = {};
            app.ObsOutlist.Tag = 'Inputlist';
            % Show the figure after all components are created
            app.PIETOOLS2021aUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIETOOLS_2021a

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIETOOLS2021aUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIETOOLS2021aUIFigure)
        end
    end
end